classdef filetesting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ExploreButton                matlab.ui.control.Button
        MeshInputFileTextAreaLabel   matlab.ui.control.Label
        MeshInputFileTextArea        matlab.ui.control.TextArea
        OutputFilenameTextAreaLabel  matlab.ui.control.Label
        OutputFilenameTextArea       matlab.ui.control.TextArea
        ExploreButton_2              matlab.ui.control.Button
        feaBeam3DLabel               matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExploreButton
        function ExploreButtonPushed(app, event)
            [file,path] = uigetfile;
            path_str = string(path);
            file_str = string(file);
            app.MeshInputFileTextArea.Value = path_str+file_str;
        end

        % Button pushed function: ExploreButton_2
        function ExploreButton_2Pushed(app, event)
            [file,path] = uigetfile;
            path2_str = string(path);
            app.OutputFilenameTextArea.Value = path2_str;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExploreButton = uibutton(app.UIFigure, 'push');
            app.ExploreButton.ButtonPushedFcn = createCallbackFcn(app, @ExploreButtonPushed, true);
            app.ExploreButton.Text = 'Explore';
            app.MeshInputFileTextAreaLabel = uilabel(app.UIFigure);
            app.MeshInputFileTextAreaLabel.Text = 'Mesh Input File';
            app.MeshInputFileTextArea = uitextarea(app.UIFigure);
            app.MeshInputFileTextArea.Editable = 'off';
            app.OutputFilenameTextAreaLabel = uilabel(app.UIFigure);
            app.OutputFilenameTextAreaLabel.Text = 'Output Filename';
            app.OutputFilenameTextArea = uitextarea(app.UIFigure);
            app.OutputFilenameTextArea.Editable = 'off';
            app.ExploreButton_2 = uibutton(app.UIFigure, 'push');
            app.ExploreButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExploreButton_2Pushed, true);
            app.ExploreButton_2.Text = 'Explore';
            app.feaBeam3DLabel = uilabel(app.UIFigure);
            app.feaBeam3DLabel.Text = 'feaBeam 3D';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo-es-mobile.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filetesting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end